classdef TrialAnalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ApplyRemoveCrossTalkalgorithmCheckBox  matlab.ui.control.CheckBox
        ReflectSignalforfilteringCheckBox  matlab.ui.control.CheckBox
        rightarrowLabel               matlab.ui.control.Label
        DBfileEditField               matlab.ui.control.EditField
        DBfileEditFieldLabel          matlab.ui.control.Label
        relativepathCheckBox          matlab.ui.control.CheckBox
        BrowseButton                  matlab.ui.control.Button
        addtoDBCheckBox               matlab.ui.control.CheckBox
        MainPathEditField             matlab.ui.control.EditField
        MainsubjectfolderLabel        matlab.ui.control.Label
        INAIL_2                       matlab.ui.control.Image
        UNIPD_2                       matlab.ui.control.Image
        INAIL                         matlab.ui.control.Image
        TrialAnalyzerLabel            matlab.ui.control.Label
        OLYMPIA                       matlab.ui.control.Image
        UNIPD                         matlab.ui.control.Image
        matCheckBox                   matlab.ui.control.CheckBox
        OpenC3DafterexportCheckBox    matlab.ui.control.CheckBox
        CombineForcePlatesLabel       matlab.ui.control.Label
        Button                        matlab.ui.control.Button
        ClearUnlabeledEditField       matlab.ui.control.EditField
        ClearUnlabeledTracksCheckBox  matlab.ui.control.CheckBox
        CombineForcePlatesCheckBox    matlab.ui.control.CheckBox
        FileGroundDropDown            matlab.ui.control.DropDown
        RotateGroundDropDownLabel     matlab.ui.control.Label
        TargetGroundDropDown          matlab.ui.control.DropDown
        EventThresholdEditField       matlab.ui.control.NumericEditField
        ThresholdNEditField_2Label    matlab.ui.control.Label
        ValidThresholdNEditField      matlab.ui.control.NumericEditField
        ValidThresholdNLabel          matlab.ui.control.Label
        DetectEventsCheckBox          matlab.ui.control.CheckBox
        SaveButton                    matlab.ui.control.Button
        RunAnalysisButton             matlab.ui.control.Button
        combinedmatCheckBox           matlab.ui.control.CheckBox
        FileSuffixEditField           matlab.ui.control.EditField
        FileSuffixEditFieldLabel      matlab.ui.control.Label
        SAVEOPTIONSLabel              matlab.ui.control.Label
        C3DCheckBox                   matlab.ui.control.CheckBox
        UITable                       matlab.ui.control.Table
        FORCEPLATESLabel              matlab.ui.control.Label
        ApplySVDoptimizationCheckBox  matlab.ui.control.CheckBox
        WorkingUnitsSwitch            matlab.ui.control.Switch
        WorkingUnitsSwitchLabel       matlab.ui.control.Label
        KINEMATICSLabel               matlab.ui.control.Label
        CrossTalkRemovalOptionsLabel  matlab.ui.control.Label
        MaxContactsEditField          matlab.ui.control.NumericEditField
        MaxContactsEditFieldLabel     matlab.ui.control.Label
        MaxDurationmsEditField        matlab.ui.control.NumericEditField
        MaxDurationmsEditFieldLabel   matlab.ui.control.Label
        ForceCutOffHzEditField        matlab.ui.control.NumericEditField
        CutOffHzEditField_2Label      matlab.ui.control.Label
        ForceOrderEditField           matlab.ui.control.NumericEditField
        OrderEditField_2Label         matlab.ui.control.Label
        KinematicCutOffHzEditField    matlab.ui.control.NumericEditField
        CutOffHzEditFieldLabel        matlab.ui.control.Label
        KinematicOrderEditField       matlab.ui.control.NumericEditField
        OrderEditFieldLabel           matlab.ui.control.Label
        GENERALLabel                  matlab.ui.control.Label
        FilterForcePlatesCheckBox     matlab.ui.control.CheckBox
        FilterKinematicsCheckBox      matlab.ui.control.CheckBox
        AddTrialtoWorkspaceButton     matlab.ui.control.Button
    end

    
    properties (Access = private)
        
        rootdir=cd; % Description
        filedir string;
        mainroot
        TrialGroup
        XML;
        JSON;
        DatabaseFile;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainroot, xmlchoice, jsonchoice)
           arguments
                app;
                mainroot (1,1) string = cd;
                xmlchoice (1,1) logical = 1;
                jsonchoice (1,1) logical = 1;
            end

            try
                app.mainroot = mainroot;
                app.MainPathEditField.Value = mainroot;
                %app.DatabaseFile=fullfile(mainroot,'ProcessedTrialList.xlsx');
            catch
                msg = msgbox("Please select or create a Subject folder to save the config file!", "No subject configuration file was found", "warn", "modal");
                uiwait(msg);
            end 
            app.XML = xmlchoice;
            app.JSON = jsonchoice;
        end

        % Button pushed function: AddTrialtoWorkspaceButton
        function AddTrialtoWorkspaceButtonPushed(app, event)
            if isempty(app.filedir)
            [f,p]=uigetfile(fullfile(app.rootdir,['*.c3d;*.mat;*.configuration']),'Select Static Trial and configuration file','MultiSelect','on');
            else    
            [f,p]=uigetfile(fullfile(app.filedir(end),['*.c3d;*.mat;*.configuration']),'Select Static Trial and configuration file','MultiSelect','on');
            end
            f=string(f);
            if f=="0"   
                conf="";
                stat="";
            else
                
                conf=f(contains(f,'.configuration'));
                stat=f(contains(f,'.c3d'));
            end

            % app.filedir(end+1)=string(p);
            [f,p]=uigetfile(fullfile(p,'*.c3d'),'Select Dynamic Trial(s)','MultiSelect','on');
            f=string(f);
            if f=="0"
                return
            end
            for i=1:length(f)
                app.filedir(end+1)=string(p);
            t{i,1}="";
            t{i,2}=strrep(f(i),".c3d",""); 
            t{i,3}=strrep(stat,".c3d","");  
            t{i,4}=strrep(conf,".configuration",""); 
            
            code=regexp(f(i),"(\w+)(\d\d)_S(\d\d)_T(\d\d)_",'tokens');
            code=cat(1,code{:});
            if isempty(code)
               code(1)="";
               code(2)="";
            end
            %code=cat(1,code{:});
            

            t{i,5}=code(1);
            t{i,6}=code(1)+code(2);
            t{i,7}="";
            t{i,8}=nan;
            t{i,9}="";
            t{i,10}=nan;
            end
            t=cell2table(t,"VariableNames",app.UITable.ColumnName);
            app.UITable.Data=[app.UITable.Data;t];
            app.SaveButton.Enable=false;
            app.addtoDBCheckBox.Enable=false;
            app.addtoDBCheckBox.Value=false;
            % end
        end

        % Button pushed function: RunAnalysisButton
        function RunAnalysisButtonPushed(app, event)
            tr=app.UITable.Data;
            h=height(tr);
            for t=1:h
                app.UITable.Data.Status(t)="ANALYZING...";
                % try
                if contains(app.TargetGroundDropDown.Value,"Z-UP")
                    g=[0 0 -9.806];
                elseif contains(app.TargetGroundDropDown.Value,"Y-UP")
                    g=[0 -9.806 0];
                end
                if contains(app.WorkingUnitsSwitch.Value,"kg")
                   tu="m";
                else
                   tu="mm";
                end
                if app.ClearUnlabeledTracksCheckBox.Value
                    cl=app.ClearUnlabeledEditField.Value;
                else
                    cl="";
                end
                if app.CombineForcePlatesCheckBox.Value
                index='all';
                else
                index=[];
                end
                T2=dropDown2T(app.TargetGroundDropDown.Value);
                T1=dropDown2T(app.FileGroundDropDown.Value);
                T=T1/T2;
                if app.ApplySVDoptimizationCheckBox.Value
                   svdmode="replace";
                else
                   svdmode="ignore";
                end
                [dyn,stat]=DynamicTrialProcessor(fullfile(app.filedir(t),tr.Trial(t)+".c3d"),...
                        "StaticTrial",fullfile(app.filedir(t),tr.Static(t)+".c3d"),...
                        "Configuration",fullfile(app.filedir(t),tr.Configuration(t)+".configuration"),...
                        "TrialUnits",tu, ...
                        "RotateGround",T,...
                        "ClearUnlabeled",cl,...
                        "KinematicFilterOrder",app.KinematicOrderEditField.Value*app.FilterKinematicsCheckBox.Value,"KinematicFilterCutOff",app.KinematicCutOffHzEditField.Value,...
                        "CleanSignal",app.FilterForcePlatesCheckBox.Value,...
                        "FilterOrder",app.ForceOrderEditField.Value,"FilterCutOff",app.ForceCutOffHzEditField.Value,...
                        "ActiveThreshold",app.ValidThresholdNEditField.Value*app.ApplyRemoveCrossTalkalgorithmCheckBox.Value,"MaxNumContacts",app.MaxContactsEditField.Value,...
                        "MaxRadius",app.MaxDurationmsEditField.Value*app.ApplyRemoveCrossTalkalgorithmCheckBox.Value,...
                        "Reflect",app.ReflectSignalforfilteringCheckBox.Value,...
                        "CombineFP",index,"GravityVector",g,...
                        "SVDmode",svdmode,"EditEvents",app.DetectEventsCheckBox.Value,"EventTreshold",app.EventThresholdEditField.Value);

                app.TrialGroup.Static=stat;
                app.TrialGroup.Dynamic(t)=dyn;
                app.UITable.Data.Status(t)="ANALYSIS SUCCESS!";
                app.SaveButton.Enable=true;
                app.addtoDBCheckBox.Enable=true;
                % catch ME
                %     app.UITable.Data.Status(t)="ERROR:" +ME.message;
                %     warning(ME.message);
                % 
                % end
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
                  path=app.rootdir;
                  DBindex=false(height(app.UITable.Data.Trial),1);
                  if app.C3DCheckBox.Value||app.matCheckBox.Value %if any is true user wants to save
                      for i=1:length(app.TrialGroup) %each TrialGroup has 1 static and N associated dynamic trials
                          j=1;
                          for dyn=[app.TrialGroup(i).Dynamic]
                              %set default name
                              [path,name]=fileparts(dyn.c3dfile);
                              DBindex=DBindex|matches(app.UITable.Data.Trial,name);
                              def_name=[name,app.FileSuffixEditField.Value];
    
                              %save the matfile
                              if app.matCheckBox.Value
                                  [name,path]=uiputfile(fullfile(path,[def_name '.mat']));
                                  if name==0 %no file selected
                                     %skip saving
                                  else 
                                  [~,def_name]=fileparts(name);
                                  stat=app.TrialGroup(i).Static;
                                  matfile(j)=string(fullfile(path,name));
                                  save(matfile(j),'dyn','stat');  
                                  j=j+1;
                                  end 
                              end
    
                              if app.C3DCheckBox.Value
                                  [name,path]=uiputfile(fullfile(path,[def_name '.c3d']));
                                  if name==0 %no file selected
                                     %skip saving
                                  else
                                  dyn.writeC3D(fullfile(path,name),'newfile');

                                  if app.OpenC3DafterexportCheckBox.Value
                                      try
                                      winopen(fullfile(path,name));
                                      catch
                                      warning("Unable to open C3D file for preview!")
                                      end
                                  end
                                  end
                              end
    
                              
                              app.UITable.Data.Status(DBindex)="SAVED!";
                          
                          end
                      end
                  end
                  if app.combinedmatCheckBox.Value
                     TrialGroup=app.TrialGroup;
                     uisave('TrialGroup',path);
                  end

                  % aggiungere parte in cui generi XML/JSON con parole
                  % chiave (da salvare in ViconData):
                  % - patient classification (TF/TT/...)
                  % - allineamento
                  % - nome piede
                  % - categoria piede
                  % a cui si devono associare la lista dei file statico e
                  % dinamici utilizzati
                  % <processing patient_class="" alignment="" foot="" cat="">
                  %     <stat_file>vattelappesca.c3d</stat_file>
                  %     <dyn_file>file1.c3d</dyn_file>
                  %     <dyn_file>file2.c3d</dyn_file>
                  %     <dyn_file>file3.c3d</dyn_file>
                  % </processing>
                  

                  if app.addtoDBCheckBox.Value
                     if app.DBfileEditField.Value==""
                        app.BrowseButton.ButtonPushedFcn;
                     end
                     if app.relativepathCheckBox.Value
                     path=fileparts(app.DBfileEditField.Value); 
                     else
                     path="";
                     end

                     T=[app.UITable.Data(DBindex,2:end),array2table((strrep(matfile(DBindex),path,"")+".mat")',"VariableNames","MATfile")];
                     if isfile(app.DBfileEditField.Value)
                     writetable(T,app.DBfileEditField.Value,"WriteMode","append");
                     end
                  end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            [~,indx]=listdlg("ListString",app.UITable.Data.Trial,"PromptString","Select Trials to remove","SelectionMode","multiple");
            app.UITable.Data(indx,:)=[];
            app.filedir(indx)=[];
        end

        % Value changed function: C3DCheckBox
        function C3DCheckBoxValueChanged(app, event)
            value = app.C3DCheckBox.Value;
            app.OpenC3DafterexportCheckBox.Enable=value;
            app.OpenC3DafterexportCheckBox.Value=false;
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
             [f,p]=uigetfile(fullfile(app.mainroot,'*.xlsx'),"Select the DB file");
             if f==0
             [f,p]=uiputfile(fullfile(app.mainroot,'*.xlsx'),"Create DB fi");
             end
             app.DBfileEditField.Value=fullfile(p,f);
             %app.Label.Text=app.DatabaseFile;
        end

        % Value changed function: matCheckBox
        function matCheckBoxValueChanged(app, event)
            value = app.matCheckBox.Value;
            app.addtoDBCheckBox.Enable=value;
            app.addtoDBCheckBox.Value=false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AddTrialtoWorkspaceButton = uibutton(app.UIFigure, 'push');
            app.AddTrialtoWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @AddTrialtoWorkspaceButtonPushed, true);
            app.AddTrialtoWorkspaceButton.Tooltip = {'Import a static trial with a configuration and one or more dynamic trials into the workspace'};
            app.AddTrialtoWorkspaceButton.Text = 'Add Trial to Workspace';
            app.FilterKinematicsCheckBox = uicheckbox(app.UIFigure);
            app.FilterKinematicsCheckBox.Tooltip = {'Apply a Butterworth low-pass filter using filtfilt function. Note that the specified Order will be doubled by the filtfilt algorithm'};
            app.FilterKinematicsCheckBox.Text = 'Filter Kinematics';
            app.FilterKinematicsCheckBox.Value = true;
            app.FilterForcePlatesCheckBox = uicheckbox(app.UIFigure);
            app.FilterForcePlatesCheckBox.Tooltip = {'Applies a low-pass Butterworth filter to the force plates using the filtfilt function. Note that the function will double the specified order.'};
            app.FilterForcePlatesCheckBox.Text = 'Filter ForcePlates';
            app.FilterForcePlatesCheckBox.Value = true;
            app.GENERALLabel = uilabel(app.UIFigure);
            app.GENERALLabel.Text = 'GENERAL';
            app.OrderEditFieldLabel = uilabel(app.UIFigure);
            app.OrderEditFieldLabel.Text = 'Order';
            app.KinematicOrderEditField = uieditfield(app.UIFigure, 'numeric');
            app.KinematicOrderEditField.Limits = [1 Inf];
            app.KinematicOrderEditField.Value = 2;
            app.CutOffHzEditFieldLabel = uilabel(app.UIFigure);
            app.CutOffHzEditFieldLabel.Text = 'Cut-Off (Hz)';
            app.KinematicCutOffHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.KinematicCutOffHzEditField.Limits = [0 Inf];
            app.KinematicCutOffHzEditField.Value = 15;
            app.OrderEditField_2Label = uilabel(app.UIFigure);
            app.OrderEditField_2Label.Text = 'Order';
            app.ForceOrderEditField = uieditfield(app.UIFigure, 'numeric');
            app.ForceOrderEditField.Limits = [1 Inf];
            app.ForceOrderEditField.Value = 2;
            app.CutOffHzEditField_2Label = uilabel(app.UIFigure);
            app.CutOffHzEditField_2Label.Text = 'Cut-Off (Hz)';
            app.ForceCutOffHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.ForceCutOffHzEditField.Limits = [1 Inf];
            app.ForceCutOffHzEditField.Value = 100;
            app.MaxDurationmsEditFieldLabel = uilabel(app.UIFigure);
            app.MaxDurationmsEditFieldLabel.Tooltip = {'This parameter sets the width of a mask  centered on the maximum value recorded by the individual force platform. Any value outside of this mask will be automatically set 0 '};
            app.MaxDurationmsEditFieldLabel.Text = 'Max Duration (ms)';
            app.MaxDurationmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxDurationmsEditField.Limits = [0 Inf];
            app.MaxDurationmsEditField.Tooltip = {'Maxiumum duration of a contact'};
            app.MaxDurationmsEditField.Value = 100;
            app.MaxContactsEditFieldLabel = uilabel(app.UIFigure);
            app.MaxContactsEditFieldLabel.Tooltip = {'This parameter controls the maximum number of contacts allowed for each force platform.'};
            app.MaxContactsEditFieldLabel.Text = 'Max Contacts';
            app.MaxContactsEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxContactsEditField.Limits = [1 1000000];
            app.MaxContactsEditField.Tooltip = {'Maximum number of contacts for each force platform'};
            app.MaxContactsEditField.Value = 100;
            app.CrossTalkRemovalOptionsLabel = uilabel(app.UIFigure);
            app.CrossTalkRemovalOptionsLabel.Tooltip = {'Options for the cross-talk removal which will be applied  during filtering'};
            app.CrossTalkRemovalOptionsLabel.Text = 'Cross-Talk Removal Options';
            app.KINEMATICSLabel = uilabel(app.UIFigure);
            app.KINEMATICSLabel.Text = 'KINEMATICS';
            app.WorkingUnitsSwitchLabel = uilabel(app.UIFigure);
            app.WorkingUnitsSwitchLabel.Enable = 'off';
            app.WorkingUnitsSwitchLabel.Visible = 'off';
            app.WorkingUnitsSwitchLabel.Text = 'Working Units';
            app.WorkingUnitsSwitch = uiswitch(app.UIFigure, 'slider');
            app.WorkingUnitsSwitch.Items = {'N, kg, m, s', 'N, t, mm, s'};
            app.WorkingUnitsSwitch.Enable = 'off';
            app.WorkingUnitsSwitch.Visible = 'off';
            app.WorkingUnitsSwitch.Tooltip = {'Sets the acquisition units to either one of the two desired unit systems'};
            app.WorkingUnitsSwitch.Value = 'N, t, mm, s';
            app.ApplySVDoptimizationCheckBox = uicheckbox(app.UIFigure);
            app.ApplySVDoptimizationCheckBox.Tooltip = {'Apply the SVD algorithm to optimize the marker position based on cluster coordinates. This won''t affect the SVD reconstruction of static-only markers'};
            app.ApplySVDoptimizationCheckBox.Text = 'Apply SVD optimization';
            app.ApplySVDoptimizationCheckBox.Value = true;
            app.FORCEPLATESLabel = uilabel(app.UIFigure);
            app.FORCEPLATESLabel.Text = 'FORCE PLATES';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Status'; 'Trial'; 'Static'; 'Configuration'; 'Classification'; 'Subject'; 'FootModel'; 'FootCat'; 'SocketName'; 'SocketAlignment'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.C3DCheckBox = uicheckbox(app.UIFigure);
            app.C3DCheckBox.ValueChangedFcn = createCallbackFcn(app, @C3DCheckBoxValueChanged, true);
            app.C3DCheckBox.Tooltip = {'Combine all the trials with the same static trial to a single mat file'};
            app.C3DCheckBox.Text = 'C3D';
            app.SAVEOPTIONSLabel = uilabel(app.UIFigure);
            app.SAVEOPTIONSLabel.Tooltip = {'Combine all the trials with the same static trial to a single mat file'};
            app.SAVEOPTIONSLabel.Text = 'SAVE OPTIONS';
            app.FileSuffixEditFieldLabel = uilabel(app.UIFigure);
            app.FileSuffixEditFieldLabel.Tooltip = {'Combine all the trials with the same static trial to a single mat file'};
            app.FileSuffixEditFieldLabel.Text = 'File Suffix';
            app.FileSuffixEditField = uieditfield(app.UIFigure, 'text');
            app.FileSuffixEditField.Tooltip = {'If a suffix is specified, the filename will be automatically set to the original plus the suffix, else the user will have to select the destination file'};
            app.FileSuffixEditField.Value = '_PROCESSED';
            app.combinedmatCheckBox = uicheckbox(app.UIFigure);
            app.combinedmatCheckBox.Tooltip = {'Combine all the trials with the same static trial to a single mat file'};
            app.combinedmatCheckBox.Text = 'combined .mat';
            app.RunAnalysisButton = uibutton(app.UIFigure, 'push');
            app.RunAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButtonPushed, true);
            app.RunAnalysisButton.Tooltip = {'Run the analysis for the workspace using the selected options.'};
            app.RunAnalysisButton.Text = 'RunAnalysis';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Tooltip = {'Save the workspace trials to the selected destinations'};
            app.SaveButton.Text = 'Save';
            app.DetectEventsCheckBox = uicheckbox(app.UIFigure);
            app.DetectEventsCheckBox.Tooltip = {'Detects the FootStrike and FootOff events using force platform data. Any existing FootOff and FootStrike event will be deleted.'};
            app.DetectEventsCheckBox.Text = 'Detect Events';
            app.ValidThresholdNLabel = uilabel(app.UIFigure);
            app.ValidThresholdNLabel.Tooltip = {'Sets the threshold under which the forceplatform could not be considered active (i.e., is recording only noise and cross-talk)'};
            app.ValidThresholdNLabel.Text = 'Valid Threshold (N)';
            app.ValidThresholdNEditField = uieditfield(app.UIFigure, 'numeric');
            app.ValidThresholdNEditField.Limits = [1 Inf];
            app.ValidThresholdNEditField.Tooltip = {'Threshold for determining if a force platform is active or not'};
            app.ValidThresholdNEditField.Value = 100;
            app.ThresholdNEditField_2Label = uilabel(app.UIFigure);
            app.ThresholdNEditField_2Label.Text = 'Threshold (N)';
            app.EventThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.EventThresholdEditField.Limits = [1 Inf];
            app.EventThresholdEditField.Tooltip = {'Threshold for event detection'};
            app.EventThresholdEditField.Value = 20;
            app.TargetGroundDropDown = uidropdown(app.UIFigure);
            app.TargetGroundDropDown.Items = {'Y-UP, Z-LAT', 'Z-UP, X-LAT', 'Z-UP, Y-LAT'};
            app.TargetGroundDropDown.Value = 'Z-UP, X-LAT';
            app.RotateGroundDropDownLabel = uilabel(app.UIFigure);
            app.RotateGroundDropDownLabel.Tooltip = {'Applies a rotation to point coordinates and force plates to adjust the ground orientation.'};
            app.RotateGroundDropDownLabel.Text = 'Rotate Ground';
            app.FileGroundDropDown = uidropdown(app.UIFigure);
            app.FileGroundDropDown.Items = {'Y-UP, Z-LAT', 'Z-UP, X-LAT', 'Z-UP, Y-LAT'};
            app.FileGroundDropDown.Value = 'Z-UP, X-LAT';
            app.CombineForcePlatesCheckBox = uicheckbox(app.UIFigure);
            app.CombineForcePlatesCheckBox.Tooltip = {''};
            app.CombineForcePlatesCheckBox.Text = '';
            app.CombineForcePlatesCheckBox.Value = true;
            app.ClearUnlabeledTracksCheckBox = uicheckbox(app.UIFigure);
            app.ClearUnlabeledTracksCheckBox.Tooltip = {'Removes any unlabeled point from the trial, unlabeled track are usually identified by a C_ prefix'};
            app.ClearUnlabeledTracksCheckBox.Text = 'Clear Unlabeled Tracks';
            app.ClearUnlabeledTracksCheckBox.Value = true;
            app.ClearUnlabeledEditField = uieditfield(app.UIFigure, 'text');
            app.ClearUnlabeledEditField.Value = 'C_';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Tooltip = {'Remove items from the workspace'};
            app.Button.Text = '-';
            app.CombineForcePlatesLabel = uilabel(app.UIFigure);
            app.CombineForcePlatesLabel.Tooltip = {'Combines all the force plates in the acquisition to a single one'};
            app.CombineForcePlatesLabel.Text = 'Combine ForcePlates';
            app.OpenC3DafterexportCheckBox = uicheckbox(app.UIFigure);
            app.OpenC3DafterexportCheckBox.Enable = 'off';
            app.OpenC3DafterexportCheckBox.Tooltip = {'Combine all the trials with the same static trial to a single mat file'};
            app.OpenC3DafterexportCheckBox.Text = 'Open C3D after export';
            app.matCheckBox = uicheckbox(app.UIFigure);
            app.matCheckBox.ValueChangedFcn = createCallbackFcn(app, @matCheckBoxValueChanged, true);
            app.matCheckBox.Tooltip = {'Combine all the trials with the same static trial to a single mat file'};
            app.matCheckBox.Text = '.mat';
            app.UNIPD = uiimage(app.UIFigure);
            app.UNIPD.ImageSource = 'Athletics_pictogram_Paralympics.png';
            app.OLYMPIA = uiimage(app.UIFigure);
            app.OLYMPIA.ImageSource = 'olympia.png';
            app.TrialAnalyzerLabel = uilabel(app.UIFigure);
            app.TrialAnalyzerLabel.Text = 'Trial Analyzer';
            app.INAIL = uiimage(app.UIFigure);
            app.INAIL.ImageSource = 'inail.jpg';
            app.UNIPD_2 = uiimage(app.UIFigure);
            app.UNIPD_2.ImageSource = 'UNIPD.png';
            app.INAIL_2 = uiimage(app.UIFigure);
            app.INAIL_2.ImageSource = 'Logo_Univr_Department_of_Engineering_for_Innovation Medicine.png';
            app.MainsubjectfolderLabel = uilabel(app.UIFigure);
            app.MainsubjectfolderLabel.Text = 'Main working directory:';
            app.MainPathEditField = uieditfield(app.UIFigure, 'text');
            app.MainPathEditField.Enable = 'off';
            app.addtoDBCheckBox = uicheckbox(app.UIFigure);
            app.addtoDBCheckBox.Enable = 'off';
            app.addtoDBCheckBox.Tooltip = {'Add the trials in the workspace to the selected excel Database'};
            app.addtoDBCheckBox.Text = 'add to DB';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Tooltip = {'Open a db file or create a new one'};
            app.BrowseButton.Text = 'Browse...';
            app.relativepathCheckBox = uicheckbox(app.UIFigure);
            app.relativepathCheckBox.Tooltip = {'Set the path of the file in the DB to be relative with respect to the DB'};
            app.relativepathCheckBox.Text = 'relative path';
            app.relativepathCheckBox.Value = true;
            app.DBfileEditFieldLabel = uilabel(app.UIFigure);
            app.DBfileEditFieldLabel.Text = 'DB file';
            app.DBfileEditField = uieditfield(app.UIFigure, 'text');
            app.rightarrowLabel = uilabel(app.UIFigure);
            app.rightarrowLabel.Interpreter = 'tex';
            app.rightarrowLabel.Text = '\rightarrow';
            app.ReflectSignalforfilteringCheckBox = uicheckbox(app.UIFigure);
            app.ReflectSignalforfilteringCheckBox.Text = 'Reflect Signal for filtering';
            app.ApplyRemoveCrossTalkalgorithmCheckBox = uicheckbox(app.UIFigure);
            app.ApplyRemoveCrossTalkalgorithmCheckBox.Text = 'Apply Remove Cross-Talk algorithm';
            app.ApplyRemoveCrossTalkalgorithmCheckBox.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrialAnalyzer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end